classdef voiceRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpeechRecognitionUIFigure     matlab.ui.Figure
        NameofthePersonTextArea       matlab.ui.control.TextArea
        NameofthePersonTextAreaLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        TimeTab                       matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        PreprocessedTab               matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        ProcessedTab                  matlab.ui.container.Tab
        ResultsTextArea               matlab.ui.control.TextArea
        ResultsTextAreaLabel          matlab.ui.control.Label
        UIAxes5                       matlab.ui.control.UIAxes
        FrecuencyTab                  matlab.ui.container.Tab
        MagnitudeButtonGroup          matlab.ui.container.ButtonGroup
        DBButton                      matlab.ui.control.RadioButton
        NormalButton                  matlab.ui.control.RadioButton
        MenuButtonGroup               matlab.ui.container.ButtonGroup
        AudiomatchButton              matlab.ui.control.Button
        PreprocessedButton            matlab.ui.control.Button
        TimeButton                    matlab.ui.control.Button
        UIAxes4                       matlab.ui.control.UIAxes
        ComparationTab                matlab.ui.container.Tab
        AudioCapturedLabel            matlab.ui.control.Label
        AudioMatchLabel               matlab.ui.control.Label
        UIAxes3_2                     matlab.ui.control.UIAxes
        DataBaseTab                   matlab.ui.container.Tab
        ModificarButton_5             matlab.ui.control.Button
        ModificarButton_4             matlab.ui.control.Button
        ModificarButton_3             matlab.ui.control.Button
        ModificarButton_2             matlab.ui.control.Button
        ModificarButton               matlab.ui.control.Button
        Persona5EditField             matlab.ui.control.EditField
        Person5EditFieldLabel         matlab.ui.control.Label
        Persona4EditField             matlab.ui.control.EditField
        Person4EditFieldLabel         matlab.ui.control.Label
        Persona3EditField             matlab.ui.control.EditField
        Person3EditFieldLabel         matlab.ui.control.Label
        Persona2EditField             matlab.ui.control.EditField
        Person2EditFieldLabel         matlab.ui.control.Label
        Persona1EditField             matlab.ui.control.EditField
        Person1EditFieldLabel         matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        SaveRecorder3Button           matlab.ui.control.Button
        SaveRecorder2Button           matlab.ui.control.Button
        SaveRecorder1Button           matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        UploadfileTab                 matlab.ui.container.Tab
        MenuPanel                     matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        AudioButtonGroup              matlab.ui.container.ButtonGroup
        Button_12                     matlab.ui.control.RadioButton
        Button_11                     matlab.ui.control.RadioButton
        Button_10                     matlab.ui.control.RadioButton
        ConfirmButton                 matlab.ui.control.Button
        UploadfileButton              matlab.ui.control.Button
        PersonButtonGroup             matlab.ui.container.ButtonGroup
        CapturedButton                matlab.ui.control.RadioButton
        Button_9                      matlab.ui.control.RadioButton
        Button_8                      matlab.ui.control.RadioButton
        Button_7                      matlab.ui.control.RadioButton
        Button_6                      matlab.ui.control.RadioButton
        Button_5                      matlab.ui.control.RadioButton
        UIAxes6                       matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu_2                        matlab.ui.container.Menu
        Menu2_2                       matlab.ui.container.Menu
    end

    
    properties (Access = public)
        timeValuesCapture = [];          % Arreglo con los valores tomados de la capura de datos.
        preProcesadoValores = [];        % Arreglo con los valores preprocesados.
        lineas_nombres = []              % Nombres de las personas en la base de datos.
    end
    
    properties (Access = private)
        audioPlayer = [];                % Propiedad para reconocer si se está reproduciendo un audio.
        swithWindow = 0;                 % Variable para saber en que ventana está parado.
        maginitudeFrecuency = 0;         % Valor de la magnitud a representar.
        frecSignal = []                  % Arreglo con la señal transformada de fourier.

        elapsedTime = 0;                 % Variable para manejar el tiempo.
        barTime                          % Variable para controlar la barra de carga de la grabación.
        time = 3                         % Tiempo de grabación.

        fs = 8000;                       % Cambia esto según tu frecuencia de muestreo deseada
        nfft = 512;                      % Numero de puntos de la fft (512 parte positiva)
        nfilters = 40;                   % Cantidad de filtros MEL
        ncoef = 13;                      % Numero de coeficientes caracteristicos

        who = 0                          % Quién es la persona que identifico
        recorderVoicePerson = []         % Persona que grabo su audio para la base de datos. 
        changeWho = 0                    % Persona que se le va a realizar cambio de base de datos.
        archivoAbrir = "";               % Archivo a abrir.
        archivoMatxh = [];               % Audio con el que coincide la voz.

        nuevoAudio = [];                 % Audio nuevo con nueva frecuencia
    end
    
    methods (Access = public)
        %% Función para graficar los resultados de la grabación grabada:
        function graficarTime(app, xValues, yValues)
            stem(app.UIAxes, xValues, yValues, '.');
            app.timeValuesCapture = [xValues; yValues'];
            app.Button_4.Enable = "on";
        end
        
        %% Función para graficar los resultados de preProcesamiento:
        function graficarPreprocesamiento(app, xValues, yValues)
            stem(app.UIAxes3, xValues, yValues, '.');
            app.preProcesadoValores = [xValues; yValues];
        end
    end
    
    methods (Access = private)
        %% Función para graficar en el dominio frecuencial:
        function data = plot_fftStat(app, signal)
            % Realizar FFT normal
            fft_result = fft(signal);

            % Obtener la mitad positiva de la FFT
            fft_result = fft_result(1:length(fft_result)/2);
            fft_result(fft_result == 0) = eps;

            if(app.maginitudeFrecuency == 0)
                % Calcular la magnitud normal:
                fft_result_dB = (abs(fft_result) / length(fft_result));
            else
                % Calcular la magnitud en decibeles
                fft_result_dB = 20*log10(abs(fft_result) / length(fft_result));
            end

            % Generar el vector de frecuencias para la mitad positiva
            f = linspace(0, app.fs/2, length(fft_result));
            data = [fft_result_dB; f];
        end

        %% Función para contablizar el tiempo de grabación:
        function timer_callback(app)
            % Incrementar el tiempo en décimas de segundo
            app.elapsedTime = app.elapsedTime + 0.1;

            % Convertir el tiempo a segundos y microsegundos
            seconds = floor(app.elapsedTime);
            microSeconds = mod(app.elapsedTime, 1);

            % Actualizar la etiqueta de texto con el tiempo transcurrido
            value = ((seconds + microSeconds) / app.time);
            waitbar(value, app.barTime, 'Recording');
        end

        %% Modificar el nombre de la persona si cambio:
        function change_Value(app)
            carpeta = 'locutores';
            nombre_archivo = 'Personas.txt';
            ruta_completa = fullfile(carpeta, nombre_archivo);
            switch app.changeWho
                case 1
                    cadena_modificada = ['.', app.Persona1EditField.Value];
                    if ~strcmpi(cadena_modificada, app.lineas_nombres{1})
                        app.lineas_nombres{1} = cadena_modificada;
                        % Abrir el archivo para Escritura
                        fid = fopen(ruta_completa, 'w');
                        fprintf(fid, '%s\n', cadena_modificada);
                        fprintf(fid, '%s\n', app.lineas_nombres{2});
                        fprintf(fid, '%s\n', app.lineas_nombres{3});
                        fprintf(fid, '%s\n', app.lineas_nombres{4});
                        fprintf(fid, '%s\n', app.lineas_nombres{5});
                        % Cerrar el archivo
                        fclose(fid);
                    end
                case 2
                    cadena_modificada = ['.', app.Persona2EditField.Value];
                    if ~strcmpi(cadena_modificada, app.lineas_nombres{2})
                        app.lineas_nombres{2} = cadena_modificada;
                        % Abrir el archivo para Escritura
                        fid = fopen(ruta_completa, 'w');
                        fprintf(fid, '%s\n', app.lineas_nombres{1});
                        fprintf(fid, '%s\n', cadena_modificada);
                        fprintf(fid, '%s\n', app.lineas_nombres{3});
                        fprintf(fid, '%s\n', app.lineas_nombres{4});
                        fprintf(fid, '%s\n', app.lineas_nombres{5});
                        % Cerrar el archivo
                        fclose(fid);
                    end
                case 3
                    cadena_modificada = ['.', app.Persona3EditField.Value];
                    if ~strcmpi(cadena_modificada, app.lineas_nombres{3})
                        % Abrir el archivo para Escritura
                        fid = fopen(ruta_completa, 'w');
                        app.lineas_nombres{3} = cadena_modificada;
                        fprintf(fid, '%s\n', app.lineas_nombres{1});
                        fprintf(fid, '%s\n', app.lineas_nombres{2});
                        fprintf(fid, '%s\n', cadena_modificada);
                        fprintf(fid, '%s\n', app.lineas_nombres{4});
                        fprintf(fid, '%s\n', app.lineas_nombres{5});
                        % Cerrar el archivo
                        fclose(fid);
                    end
                case 4
                    cadena_modificada = ['.', app.Persona4EditField.Value];
                    if ~strcmpi(cadena_modificada, app.lineas_nombres{4})
                        app.lineas_nombres{4} = cadena_modificada;
                        % Abrir el archivo para Escritura
                        fid = fopen(ruta_completa, 'w');
                        fprintf(fid, '%s\n', app.lineas_nombres{1});
                        fprintf(fid, '%s\n', app.lineas_nombres{2});
                        fprintf(fid, '%s\n', app.lineas_nombres{3});
                        fprintf(fid, '%s\n', cadena_modificada);
                        fprintf(fid, '%s\n', app.lineas_nombres{5});
                        % Cerrar el archivo
                        fclose(fid);
                    end
                case 5
                    cadena_modificada = ['.', app.Persona5EditField.Value];
                    if ~strcmpi(cadena_modificada, app.lineas_nombres{5})
                        app.lineas_nombres{5} = cadena_modificada;
                        % Abrir el archivo para Escritura
                        fid = fopen(ruta_completa, 'w');
                        fprintf(fid, '%s\n', app.lineas_nombres{1});
                        fprintf(fid, '%s\n', app.lineas_nombres{2});
                        fprintf(fid, '%s\n', app.lineas_nombres{3});
                        fprintf(fid, '%s\n', app.lineas_nombres{4});
                        fprintf(fid, '%s\n', cadena_modificada);
                        % Cerrar el archivo
                        fclose(fid);
                    end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            % Configura la posición de la figura para centrarla:
            movegui(app.SpeechRecognitionUIFigure, 'center');
            app.swithWindow = 1;
            app.maginitudeFrecuency = 0;
            xlabel(app.UIAxes4,'Frequency (Hz)');
            % Cargar Base de datos:
            carpeta = 'locutores';
            nombre_archivo = 'personas.txt';
            ruta_completa = fullfile(carpeta, nombre_archivo);
            % Abrir el archivo para lectura
            fid = fopen(ruta_completa, 'r');
            if fid == -1
                msgbox('Error loading database.', 'Error', 'error');
                return;
            end
            % Leer cada línea del archivo
            lineas = textscan(fid, '%s', 'Delimiter', '\n');
            % Cerrar el archivo
            fclose(fid);
            app.lineas_nombres = cell(1, length(lineas{1}));
            % Extraer las líneas que contienen nombres
            for i = 1:length(lineas{1})
                % Verificar si la línea contiene un punto (indicativo de un nombre)
                if contains(lineas{1}{i}, '.')
                    % Agregar la línea al arreglo de líneas con nombres
                    app.lineas_nombres{i} = lineas{1}{i};
                end
            end
            % Asignar valores a la base de datos:
            if ~isempty(app.lineas_nombres{1})
                app.Persona1EditField.Value = strrep(app.lineas_nombres{1}, app.lineas_nombres{1}(1), '');
            end
            if ~isempty(app.lineas_nombres{2})
                app.Persona2EditField.Value = strrep(app.lineas_nombres{2}, app.lineas_nombres{2}(1), '');
            end
            if ~isempty(app.lineas_nombres{3})
                app.Persona3EditField.Value = strrep(app.lineas_nombres{3}, app.lineas_nombres{3}(1), '');
            end
            if ~isempty(app.lineas_nombres{4})
                app.Persona4EditField.Value = strrep(app.lineas_nombres{4}, app.lineas_nombres{4}(1), '');
            end
            if ~isempty(app.lineas_nombres{5})
                app.Persona5EditField.Value = strrep(app.lineas_nombres{5}, app.lineas_nombres{5}(1), '');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           % Configurar el temporizador
            app.elapsedTime = 0;
            app.Button.Enable = 'off';
            Timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @(~,~)timer_callback(app));
            recObj = audiorecorder(app.fs, 16, 1);  % Crear un objeto de grabadora de audio
            % Iniciar el temporizador
            app.barTime = waitbar(0,'Recording');
            start(Timer);
            recordblocking(recObj, app.time); % Grabar  audio
            % Detener contador.
            close(app.barTime);
            stop(Timer);
            audioData = getaudiodata(recObj);
            xlength = 0:1:length(audioData)- 1;
            % Guardar arreglo en la aplición principal:
            if ~isequal(app.Lamp.Color, [1, 0, 0])
                app.graficarTime(xlength, audioData);
            else
                app.recorderVoicePerson = audioData;
                app.SaveRecorder1Button.Enable = "on";
                app.SaveRecorder2Button.Enable = "on";
                app.SaveRecorder3Button.Enable = "on";
            end
            %Limpiar memoria:
            clear Timer recObj audioData xlength app.barTime
            if ~isequal(app.Lamp.Color, [1, 0, 0])
                app.TabGroup.SelectedTab = app.TimeTab;
            else
                app.TabGroup.SelectedTab = app.DataBaseTab;
            end
            app.Button.Enable = 'on';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            switch app.swithWindow
                case 1
                    if ~isempty(app.timeValuesCapture)
                        app.audioPlayer = audioplayer(app.timeValuesCapture(2,:), app.fs);
                        play(app.audioPlayer);
                        clear app.audioPlayer;
                    else
                        msgbox('No voice has been recorded', 'Error', 'error');
                        return;
                    end
                case 2
                    if ~isempty(app.preProcesadoValores)
                        app.audioPlayer = audioplayer(app.preProcesadoValores(2,:), app.fs);
                        play(app.audioPlayer);
                        clear app.audioPlayer;
                    else
                        msgbox('No signal has been preprocessed', 'Error', 'error');
                        return;
                    end
                case 6
                    if ~isempty(app.recorderVoicePerson)
                        app.audioPlayer = audioplayer(app.recorderVoicePerson, app.fs);
                        play(app.audioPlayer);
                        clear app.audioPlayer;
                    else
                        msgbox('No signal has been preprocessed', 'Error', 'error');
                        return;
                    end
                case 7
                    if ~isempty(app.nuevoAudio)
                        app.audioPlayer = audioplayer(app.nuevoAudio, app.fs);
                        play(app.audioPlayer);
                        clear app.audioPlayer;
                    else
                        msgbox('Non-audible signal', 'Error', 'error');
                        return;
                    end
                otherwise
                        msgbox('Non-audible signal', 'Error', 'error');
                        return;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             if isa(app.audioPlayer, 'audioplayer') && (isplaying(app.audioPlayer))
                % Detener la reproducción
                stop(app.audioPlayer);
                clear app.audioPlayer;
            end
        end

        % Button down function: TimeTab
        function TimeTabButtonDown(app, event)
            app.swithWindow = 1;
        end

        % Button down function: PreprocessedTab
        function PreprocessedTabButtonDown(app, event)
            app.swithWindow = 2;
        end

        % Button down function: FrecuencyTab
        function FrecuencyTabButtonDown(app, event)
            app.swithWindow = 3;
        end

        % Button pushed function: TimeButton
        function TimeButtonPushed(app, event)
            if ~isempty(app.timeValuesCapture)
                app.UIAxes4.Title.String = "Recorder";
                app.frecSignal = app.timeValuesCapture(2,:);
                data = app.plot_fftStat(app.frecSignal);
                plot(app.UIAxes4, data(2,:), data(1,:));
                if(app.maginitudeFrecuency == 0)
                    ylabel(app.UIAxes4,'Magnitude');
                else
                    ylabel(app.UIAxes4,'Magnitude DB');
                end
            else
                msgbox('No voice has been recorded', 'Error', 'error');
                return;
            end
        end

        % Selection changed function: MagnitudeButtonGroup
        function MagnitudeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MagnitudeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Normal')
                app.maginitudeFrecuency = 0;
            else
                app.maginitudeFrecuency = 1;
            end
            if ~isempty(app.frecSignal)
                data = app.plot_fftStat(app.frecSignal);
                plot(app.UIAxes4, data(2,:), data(1,:));
                if(app.maginitudeFrecuency == 0)
                    ylabel(app.UIAxes4,'Magnitude');
                else
                    ylabel(app.UIAxes4,'Magnitude DB');
                end
            end
        end

        % Button pushed function: PreprocessedButton
        function PreprocessedButtonPushed(app, event)
            if ~isempty(app.preProcesadoValores)
                app.UIAxes4.Title.String = "Recorder";
                app.frecSignal = app.preProcesadoValores(2,:);
                data = app.plot_fftStat(app.frecSignal);
                plot(app.UIAxes4, data(2,:), data(1,:));
                if(app.maginitudeFrecuency == 0)
                    ylabel(app.UIAxes4,'Magnitude');
                else
                    ylabel(app.UIAxes4,'Magnitude DB');
                end
            else
                msgbox('No voice has been preprocessed', 'Error', 'error');
                return;
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %% Limpiar
            cla(app.UIAxes3_2);
            cla(app.UIAxes4);
            app.archivoMatxh = [];
            %% Preprocesado:
            % Filtrar señal:
            y = Eliminar_Silencio(app.timeValuesCapture(2,:), app.fs);
            app.graficarPreprocesamiento(0:1:(length(y) -1), y)
            clear y;
            y = Segmentar(app.preProcesadoValores(2,:), app.fs);
            %% Realizar la comparación con el banco de muestras:
            y = MFCC(y, app.nfilters, app.fs, app.nfft, app.ncoef);
            % Procesar:
            matriz_Distancias = Comparar(y);
            matrizStr1 = sprintf('%.2f\t%.2f\t%.2f\n', matriz_Distancias(1,1), matriz_Distancias(1,2), matriz_Distancias(1,3));
            matrizStr2 = sprintf('%.2f\t%.2f\t%.2f\n', matriz_Distancias(2,1), matriz_Distancias(2,2), matriz_Distancias(2,3));
            matrizStr3 = sprintf('%.2f\t%.2f\t%.2f\n', matriz_Distancias(3,1), matriz_Distancias(3,2), matriz_Distancias(3,3));
            matrizStr4 = sprintf('%.2f\t%.2f\t%.2f\n', matriz_Distancias(4,1), matriz_Distancias(4,2), matriz_Distancias(4,3));
            matrizStr5 = sprintf('%.2f\t%.2f\t%.2f\n', matriz_Distancias(5,1), matriz_Distancias(5,2), matriz_Distancias(5,3));           
            app.ResultsTextArea.Value = [matrizStr1, matrizStr2, matrizStr3, matrizStr4, matrizStr5];
            app.who = Decidir(matriz_Distancias, 60);
            menor = 100;
            letra = 'A';
            switch app.who
                case 1
                    letra = 'A';
                case 2
                    letra = 'B';
                case 3
                    letra = 'C';
                case 4
                    letra = 'D';
                case 5
                    letra = 'E';
            end
            if (app.who ~= 0)
                for j = 1:3
                    nombre_celda = [letra, num2str(j)];
                    % Acceder al elemento en la fila i y columna j
                    elemento_actual = matriz_Distancias(app.who, j);
                    if(elemento_actual < menor)
                        menor = elemento_actual;
                        app.archivoAbrir = nombre_celda;
                    end
                end
            end
            imagesc(app.UIAxes5, y);
            axis(app.UIAxes5, 'image');
            app.UIAxes5.DataAspectRatio = [1 2 4];
            colormap(app.UIAxes5, "gray");
            app.TabGroup.SelectedTab = app.ProcessedTab;
            switch app.who
                case 1
                    app.NameofthePersonTextArea.Value = strrep(app.lineas_nombres{1}, app.lineas_nombres{1}(1), '');
                    ruta_archivo = ['locutores/audios/locutor_', app.archivoAbrir, '.wav']; 
                case 2
                    app.NameofthePersonTextArea.Value = strrep(app.lineas_nombres{2}, app.lineas_nombres{2}(1), '');
                    ruta_archivo = ['locutores/audios/locutor_', app.archivoAbrir, '.wav']; 
                case 3
                    app.NameofthePersonTextArea.Value = strrep(app.lineas_nombres{3}, app.lineas_nombres{3}(1), '');
                    ruta_archivo = ['locutores/audios/locutor_', app.archivoAbrir, '.wav']; 
                case 4
                    app.NameofthePersonTextArea.Value = strrep(app.lineas_nombres{4}, app.lineas_nombres{4}(1), '');
                    ruta_archivo = ['locutores/audios/locutor_', app.archivoAbrir, '.wav']; 
                case 5
                    app.NameofthePersonTextArea.Value = strrep(app.lineas_nombres{5}, app.lineas_nombres{5}(1), '');
                    ruta_archivo = ['locutores/audios/locutor_', app.archivoAbrir, '.wav']; 
                otherwise
                    app.NameofthePersonTextArea.Value = "No identificado";
            end
            if (app.who ~= 0)
                % Lee el archivo de audio
                [y, frecuency] = audioread(ruta_archivo);
                y = resample(y', app.fs, frecuency);
                app.archivoMatxh = Eliminar_Silencio(y, app.fs);
                y_normalizada = normalize(app.archivoMatxh,'range', [-1, 1]);
                preprocessedTab_normalizada = normalize(app.preProcesadoValores(2,:), 'range', [-1, 1]);
                stem(app.UIAxes3_2, 0:1:(length(y_normalizada)-1), y_normalizada, '.');
                hold(app.UIAxes3_2, 'on');  % Mantener la gráfica actual
                stem(app.UIAxes3_2, 0:1:(length(preprocessedTab_normalizada)-1), preprocessedTab_normalizada, '.');
                hold(app.UIAxes3_2, 'off');
            end
            app.Button_4.Enable = 'off';
            clear y;
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            %Modificaciones de apariencia:
            app.ModificarButton_2.Text = "Modify 2";
            app.ModificarButton_3.Text = "Modify 3";
            app.ModificarButton_4.Text = "Modify 4";
            app.ModificarButton_5.Text = "Modify 5";
            app.Persona2EditField.Editable = "off";
            app.Persona3EditField.Editable = "off";
            app.Persona4EditField.Editable = "off";
            app.Persona5EditField.Editable = "off";
            app.Lamp.Color = [0,1,0];
            if app.ModificarButton.Text ~= "Save"
                app.Lamp.Color = [1,0,0];
                app.ModificarButton.Text = "Save";
                app.Persona1EditField.Editable = 'on';
                app.changeWho = 1;
            else
                app.Persona1EditField.Editable = 'off';
                app.ModificarButton.Text = "Modify 1";
                app.SaveRecorder1Button.Enable = "off";
                app.SaveRecorder2Button.Enable = "off";
                app.SaveRecorder3Button.Enable = "off";
                app.change_Value();
            end
            app.recorderVoicePerson = [];
        end

        % Button pushed function: ModificarButton_2
        function ModificarButton_2Pushed(app, event)
            %Modificaciones de apariencia:
            app.ModificarButton.Text = "Modify 1";
            app.ModificarButton_3.Text = "Modify 3";
            app.ModificarButton_4.Text = "Modify 4";
            app.ModificarButton_5.Text = "Modify 5";
            app.Persona1EditField.Editable = "off";
            app.Persona3EditField.Editable = "off";
            app.Persona4EditField.Editable = "off";
            app.Persona5EditField.Editable = "off";
            app.Lamp.Color = [0,1,0];
            if app.ModificarButton_2.Text ~= "Save"
                app.Lamp.Color = [1,0,0];
                app.ModificarButton_2.Text = "Save";
                app.Persona2EditField.Editable = 'on';
                app.changeWho = 2;
            else
                app.ModificarButton_2.Text = "Modify 2";
                app.Persona2EditField.Editable = 'off';
                app.SaveRecorder1Button.Enable = "off";
                app.SaveRecorder2Button.Enable = "off";
                app.SaveRecorder3Button.Enable = "off";
                app.change_Value();
            end
            app.recorderVoicePerson = [];
        end

        % Button pushed function: ModificarButton_3
        function ModificarButton_3Pushed(app, event)
            %Modificaciones de apariencia:
            app.ModificarButton.Text = "Modify 1";
            app.ModificarButton_2.Text = "Modify 2";
            app.ModificarButton_4.Text = "Modify 4";
            app.ModificarButton_5.Text = "Modify 5";
            app.Persona2EditField.Editable = "off";
            app.Persona1EditField.Editable = "off";
            app.Persona4EditField.Editable = "off";
            app.Persona5EditField.Editable = "off";
            app.Lamp.Color = [0,1,0];
            if app.ModificarButton_3.Text ~= "Save"
                app.Lamp.Color = [1,0,0];
                app.ModificarButton_3.Text = "Save";
                app.Persona3EditField.Editable = 'on';
                app.changeWho = 3;
            else
                app.Persona3EditField.Editable = 'off';
                app.ModificarButton_3.Text = "Modify 3";
                app.SaveRecorder1Button.Enable = "off";
                app.SaveRecorder2Button.Enable = "off";
                app.SaveRecorder3Button.Enable = "off";
                app.change_Value();
            end
            app.recorderVoicePerson = [];
        end

        % Button pushed function: ModificarButton_4
        function ModificarButton_4Pushed(app, event)
            %Modificaciones de apariencia:
            app.ModificarButton.Text = "Modify 1";
            app.ModificarButton_2.Text = "Modify 2";
            app.ModificarButton_3.Text = "Modify 3";
            app.ModificarButton_5.Text = "Modify 5";
            app.Persona2EditField.Editable = "off";
            app.Persona3EditField.Editable = "off";
            app.Persona1EditField.Editable = "off";
            app.Persona5EditField.Editable = "off";
            app.Lamp.Color = [0,1,0];
            if app.ModificarButton_4.Text ~= "Save"
                app.Lamp.Color = [1,0,0];
                app.ModificarButton_4.Text = "Save";
                app.Persona4EditField.Editable = 'on';
                app.changeWho = 4;
            else
                app.ModificarButton_4.Text = "Modify 4";
                app.Persona4EditField.Editable = 'off';
                app.SaveRecorder1Button.Enable = "off";
                app.SaveRecorder2Button.Enable = "off";
                app.SaveRecorder3Button.Enable = "off";
                app.change_Value();
            end
            app.recorderVoicePerson = [];
        end

        % Button pushed function: ModificarButton_5
        function ModificarButton_5Pushed(app, event)
            %Modificaciones de apariencia:
            app.ModificarButton.Text = "Modify 1";
            app.ModificarButton_2.Text = "Modify 2";
            app.ModificarButton_3.Text = "Modify 3";
            app.ModificarButton_4.Text = "Modify 4";
            app.Persona2EditField.Editable = "off";
            app.Persona3EditField.Editable = "off";
            app.Persona4EditField.Editable = "off";
            app.Persona1EditField.Editable = "off";
            app.Lamp.Color = [0,1,0];
            if app.ModificarButton_5.Text ~= "Save"
                app.Lamp.Color = [1,0,0];
                app.ModificarButton_5.Text = "Save";
                app.Persona5EditField.Editable = 'on';
                app.changeWho = 5;
            else
                app.ModificarButton_5.Text = "Modify 5";
                app.Persona5EditField.Editable = 'off';
                app.SaveRecorder1Button.Enable = "off";
                app.SaveRecorder2Button.Enable = "off";
                app.SaveRecorder3Button.Enable = "off";
                app.change_Value();
            end
            app.recorderVoicePerson = [];
        end

        % Button down function: DataBaseTab
        function DataBaseTabButtonDown(app, event)
            app.swithWindow = 6;
        end

        % Button pushed function: SaveRecorder1Button
        function SaveRecorder1ButtonPushed(app, event)
            % Especificar la carpeta y el nombre del archivo
            carpeta = 'locutores\audios';  % Reemplaza con la ruta completa de tu carpeta
            switch app.changeWho
                case 1
                    nombre_archivo = 'locutor_A1.wav';
                case 2
                    nombre_archivo = 'locutor_B1.wav';
                case 3
                    nombre_archivo = 'locutor_C1.wav';
                case 4
                    nombre_archivo = 'locutor_D1.wav';
                case 5
                    nombre_archivo = 'locutor_E1.wav';
            end
            % Construir la ruta completa del archivo
            ruta_completa = fullfile(carpeta, nombre_archivo);
            audiowrite(ruta_completa, app.recorderVoicePerson, app.fs);
            app.recorderVoicePerson = [];
            app.SaveRecorder1Button.Enable = "off";
            app.SaveRecorder2Button.Enable = "off";
            app.SaveRecorder3Button.Enable = "off";
            % Reconsruir Base de datos:
            Crear_Base_Datos_MFCC(app.fs, app.nfft, app.nfilters, app.ncoef)
        end

        % Button pushed function: SaveRecorder2Button
        function SaveRecorder2ButtonPushed(app, event)
            % Especificar la carpeta y el nombre del archivo
            carpeta = 'locutores\audios';  % Reemplaza con la ruta completa de tu carpeta
            switch app.changeWho
                case 1
                    nombre_archivo = 'locutor_A2.wav';
                case 2
                    nombre_archivo = 'locutor_B2.wav';
                case 3
                    nombre_archivo = 'locutor_C2.wav';
                case 4
                    nombre_archivo = 'locutor_D2.wav';
                case 5
                    nombre_archivo = 'locutor_E2.wav';
            end
            % Construir la ruta completa del archivo
            ruta_completa = fullfile(carpeta, nombre_archivo);
            audiowrite(ruta_completa, app.recorderVoicePerson, app.fs);
            app.recorderVoicePerson = [];
            app.SaveRecorder1Button.Enable = "off";
            app.SaveRecorder2Button.Enable = "off";
            app.SaveRecorder3Button.Enable = "off";
            % Reconsruir Base de datos:
            Crear_Base_Datos_MFCC(app.fs, app.nfft, app.nfilters, app.ncoef)
        end

        % Button pushed function: SaveRecorder3Button
        function SaveRecorder3ButtonPushed(app, event)
            % Especificar la carpeta y el nombre del archivo
            carpeta = 'locutores\audios';  % Reemplaza con la ruta completa de tu carpeta
            switch app.changeWho
                case 1
                    nombre_archivo = 'locutor_A3.wav';
                case 2
                    nombre_archivo = 'locutor_B3.wav';
                case 3
                    nombre_archivo = 'locutor_C3.wav';
                case 4
                    nombre_archivo = 'locutor_D3.wav';
                case 5
                    nombre_archivo = 'locutor_E3.wav';
            end
            % Construir la ruta completa del archivo
            ruta_completa = fullfile(carpeta, nombre_archivo);
            audiowrite(ruta_completa, app.recorderVoicePerson, app.fs);
            app.recorderVoicePerson = [];
            app.SaveRecorder1Button.Enable = "off";
            app.SaveRecorder2Button.Enable = "off";
            app.SaveRecorder3Button.Enable = "off";
            % Reconsruir Base de datos:
            Crear_Base_Datos_MFCC(app.fs, app.nfft, app.nfilters, app.ncoef)
        end

        % Button pushed function: AudiomatchButton
        function AudiomatchButtonPushed(app, event)
             if ~isempty(app.archivoMatxh)
                app.UIAxes4.Title.String = "Audio Match";
                app.frecSignal = app.archivoMatxh;
                data = app.plot_fftStat(app.frecSignal);
                plot(app.UIAxes4, data(2,:), data(1,:));
                if(app.maginitudeFrecuency == 0)
                    ylabel(app.UIAxes4,'Magnitude');
                else
                    ylabel(app.UIAxes4,'Magnitude DB');
                end
            else
                msgbox('No Match Voice.', 'Error', 'error');
                return;
            end
        end

        % Button pushed function: UploadfileButton
        function UploadfileButtonPushed(app, event)
            % Seleccionar un archivo de audio
            [archivo, ruta] = uigetfile({'*.wav;*.mp3;*.ogg;*.flac;*.aac;*.m4a', 'Audio Files (*.wav, *.mp3, *.ogg, *.flac, *.aac, *.m4a)'; '*.*', 'All Files (*.*)'}, 'Select an audio file');
            % Verificar si se seleccionó un archivo
            if isequal(archivo, 0) || isequal(ruta, 0)
                msgbox('No se seleccionó ningún archivo.', 'Error', 'error');
                return;
            else
                % Ruta completa del archivo seleccionado
                archivoCompleto = fullfile(ruta, archivo);

                % Leer el archivo de audio
                [audio, frecuenciaMuestreo] = audioread(archivoCompleto);

                % Duración y frecuencia deseada
                nuevaDuracion = 3;

                % Ajustar la duración
                if length(audio) < nuevaDuracion * frecuenciaMuestreo
                    % Si el audio es más corto, llenar con ceros
                    audio = [audio; zeros(nuevaDuracion * frecuenciaMuestreo - length(audio), 1)];
                else
                    % Si el audio es más largo, truncar
                    audio = audio(1:nuevaDuracion * frecuenciaMuestreo);
                end

                % Re-muestrear el audio a la frecuencia deseada
                app.nuevoAudio = resample(audio, app.fs, frecuenciaMuestreo);
                tiempoOriginal = (0:(length(app.nuevoAudio)-1));
                stem(app.UIAxes6, tiempoOriginal, app.nuevoAudio,'.');
            end
        end

        % Button down function: UploadfileTab
        function UploadfileTabButtonDown(app, event)
            app.swithWindow = 7;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if isempty(app.nuevoAudio)
                msgbox('No audio selected.', 'Error', 'error');
                return;
            end
            carpeta = 'locutores\audios';  % Reemplaza con la ruta completa de tu carpeta
            nombre_archivo = '';
            switch app.PersonButtonGroup.SelectedObject.Text
                case '1'
                    switch app.AudioButtonGroup.SelectedObject.Text
                        case '1'
                            nombre_archivo = 'locutor_A1.wav';
                        case '2'
                            nombre_archivo = 'locutor_A2.wav';
                        case '3'
                            nombre_archivo = 'locutor_A3.wav';
                    end
                case '2'
                    switch app.AudioButtonGroup.SelectedObject.Text
                        case '1'
                            nombre_archivo = 'locutor_B1.wav';
                        case '2'
                            nombre_archivo = 'locutor_B2.wav';
                        case '3'
                            nombre_archivo = 'locutor_B3.wav';
                    end
                case '3'
                    switch app.AudioButtonGroup.SelectedObject.Text
                        case '1'
                            nombre_archivo = 'locutor_C1.wav';
                        case '2'
                            nombre_archivo = 'locutor_C2.wav';
                        case '3'
                            nombre_archivo = 'locutor_C3.wav';
                    end
                case '4'
                    switch app.AudioButtonGroup.SelectedObject.Text
                        case '1'
                            nombre_archivo = 'locutor_D1.wav';
                        case '2'
                            nombre_archivo = 'locutor_D2.wav';
                        case '3'
                            nombre_archivo = 'locutor_D3.wav';
                    end
                case '5'
                    switch app.AudioButtonGroup.SelectedObject.Text
                        case '1'
                            nombre_archivo = 'locutor_E1.wav';
                        case '2'
                            nombre_archivo = 'locutor_E2.wav';
                        case '3'
                            nombre_archivo = 'locutor_E3.wav';
                    end
                case 'Captured'
                    app.TabGroup.SelectedTab = app.TimeTab;
                    app.graficarTime(0:1:(length(app.nuevoAudio)-1), app.nuevoAudio);
                    % Mostrar notificación de éxito con ícono de información
                    message = 'The file has been saved successfully.';
                    title = 'Success';
                    icon = 'info';
                    msgbox(message, title, icon);
                    app.Button.Enable = 'on';
                    app.nuevoAudio = [];
                    cla(app.UIAxes6);
                    return
            end
            % Guardar:
            ruta_completa = fullfile(carpeta, nombre_archivo);
            disp(ruta_completa);
            audiowrite(ruta_completa, app.nuevoAudio, app.fs);
            % Borrar audio:
            app.nuevoAudio = [];
            % Modificar Base de datos:
            Crear_Base_Datos_MFCC(app.fs, app.nfft, app.nfilters, app.ncoef)
            % Mostrar notificación de éxito con ícono de información
            message = 'The file has been saved successfully.';
            title = 'Success';
            icon = 'info';
            msgbox(message, title, icon);
            clear carpeta nombre_archivo;
        end

        % Selection changed function: PersonButtonGroup
        function PersonButtonGroupSelectionChanged(app, event)
            switch app.PersonButtonGroup.SelectedObject.Text
                case 'Captured'
                    app.AudioButtonGroup.Enable = 'off';
                otherwise
                    app.AudioButtonGroup.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SpeechRecognitionUIFigure = uifigure('Visible', 'off');
            app.SpeechRecognitionUIFigure.Name = 'Speech Recognition';
            app.Panel = uipanel(app.SpeechRecognitionUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'res', 'reproducirM.png');
            app.Button_2.Text = '';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'res', 'voz.png');
            app.Button.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'res', 'boton-detenerM.png');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'res', 'cheque.png');
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '';
            app.TabGroup = uitabgroup(app.SpeechRecognitionUIFigure);
            app.TimeTab = uitab(app.TabGroup);
            app.TimeTab.Title = 'Time';
            app.TimeTab.ButtonDownFcn = createCallbackFcn(app, @TimeTabButtonDown, true);
            app.UIAxes = uiaxes(app.TimeTab);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voice Level')
            zlabel(app.UIAxes, 'Z')
            app.PreprocessedTab = uitab(app.TabGroup);
            app.PreprocessedTab.Title = 'Preprocessed';
            app.PreprocessedTab.ButtonDownFcn = createCallbackFcn(app, @PreprocessedTabButtonDown, true);
            app.UIAxes3 = uiaxes(app.PreprocessedTab);
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Voice Level')
            zlabel(app.UIAxes3, 'Z')
            app.ProcessedTab = uitab(app.TabGroup);
            app.ProcessedTab.Title = 'Processed';
            app.UIAxes5 = uiaxes(app.ProcessedTab);
            xlabel(app.UIAxes5, 'Time')
            ylabel(app.UIAxes5, 'Index')
            zlabel(app.UIAxes5, 'Z')
            app.ResultsTextAreaLabel = uilabel(app.ProcessedTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ProcessedTab);
            app.ResultsTextArea.Editable = 'off';
            app.FrecuencyTab = uitab(app.TabGroup);
            app.FrecuencyTab.Title = 'Frecuency';
            app.FrecuencyTab.ButtonDownFcn = createCallbackFcn(app, @FrecuencyTabButtonDown, true);
            app.UIAxes4 = uiaxes(app.FrecuencyTab);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Magnitude')
            zlabel(app.UIAxes4, 'Z')
            app.MenuButtonGroup = uibuttongroup(app.FrecuencyTab);
            app.MenuButtonGroup.Title = 'Menu';
            app.TimeButton = uibutton(app.MenuButtonGroup, 'push');
            app.TimeButton.ButtonPushedFcn = createCallbackFcn(app, @TimeButtonPushed, true);
            app.TimeButton.Text = 'Time';
            app.PreprocessedButton = uibutton(app.MenuButtonGroup, 'push');
            app.PreprocessedButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessedButtonPushed, true);
            app.PreprocessedButton.Text = 'Preprocessed';
            app.AudiomatchButton = uibutton(app.MenuButtonGroup, 'push');
            app.AudiomatchButton.ButtonPushedFcn = createCallbackFcn(app, @AudiomatchButtonPushed, true);
            app.AudiomatchButton.Text = 'Audio match';
            app.MagnitudeButtonGroup = uibuttongroup(app.FrecuencyTab);
            app.MagnitudeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MagnitudeButtonGroupSelectionChanged, true);
            app.MagnitudeButtonGroup.Title = 'Magnitude';
            app.NormalButton = uiradiobutton(app.MagnitudeButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.DBButton = uiradiobutton(app.MagnitudeButtonGroup);
            app.DBButton.Text = 'DB';
            app.ComparationTab = uitab(app.TabGroup);
            app.ComparationTab.Title = 'Comparison';
            app.UIAxes3_2 = uiaxes(app.ComparationTab);
            xlabel(app.UIAxes3_2, 'Time')
            ylabel(app.UIAxes3_2, 'Voice Level')
            zlabel(app.UIAxes3_2, 'Z')
            app.AudioMatchLabel = uilabel(app.ComparationTab);
            app.AudioMatchLabel.Text = 'Audio Match';
            app.AudioCapturedLabel = uilabel(app.ComparationTab);
            app.AudioCapturedLabel.Text = 'Audio Captured';
            app.DataBaseTab = uitab(app.TabGroup);
            app.DataBaseTab.Title = 'Data Base';
            app.DataBaseTab.ButtonDownFcn = createCallbackFcn(app, @DataBaseTabButtonDown, true);
            app.Panel_2 = uipanel(app.DataBaseTab);
            app.Panel_2.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.Lamp = uilamp(app.GridLayout2);
            app.SaveRecorder1Button = uibutton(app.GridLayout2, 'push');
            app.SaveRecorder1Button.ButtonPushedFcn = createCallbackFcn(app, @SaveRecorder1ButtonPushed, true);
            app.SaveRecorder1Button.Enable = 'off';
            app.SaveRecorder1Button.Text = {'Save '; 'Recorder 1'};
            app.SaveRecorder2Button = uibutton(app.GridLayout2, 'push');
            app.SaveRecorder2Button.ButtonPushedFcn = createCallbackFcn(app, @SaveRecorder2ButtonPushed, true);
            app.SaveRecorder2Button.Enable = 'off';
            app.SaveRecorder2Button.Text = {'Save '; 'Recorder 2'};
            app.SaveRecorder3Button = uibutton(app.GridLayout2, 'push');
            app.SaveRecorder3Button.ButtonPushedFcn = createCallbackFcn(app, @SaveRecorder3ButtonPushed, true);
            app.SaveRecorder3Button.Enable = 'off';
            app.SaveRecorder3Button.Text = {'Save '; 'Recorder 3'};
            app.Person1EditFieldLabel = uilabel(app.DataBaseTab);
            app.Person1EditFieldLabel.Text = 'Person 1';
            app.Persona1EditField = uieditfield(app.DataBaseTab, 'text');
            app.Persona1EditField.Editable = 'off';
            app.Person2EditFieldLabel = uilabel(app.DataBaseTab);
            app.Person2EditFieldLabel.Text = 'Person 2';
            app.Persona2EditField = uieditfield(app.DataBaseTab, 'text');
            app.Persona2EditField.Editable = 'off';
            app.Person3EditFieldLabel = uilabel(app.DataBaseTab);
            app.Person3EditFieldLabel.Text = 'Person 3';
            app.Persona3EditField = uieditfield(app.DataBaseTab, 'text');
            app.Persona3EditField.Editable = 'off';
            app.Person4EditFieldLabel = uilabel(app.DataBaseTab);
            app.Person4EditFieldLabel.Text = 'Person 4';
            app.Persona4EditField = uieditfield(app.DataBaseTab, 'text');
            app.Persona4EditField.Editable = 'off';
            app.Person5EditFieldLabel = uilabel(app.DataBaseTab);
            app.Person5EditFieldLabel.Text = 'Person 5';
            app.Persona5EditField = uieditfield(app.DataBaseTab, 'text');
            app.Persona5EditField.Editable = 'off';
            app.ModificarButton = uibutton(app.DataBaseTab, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Text = 'Modify 1';
            app.ModificarButton_2 = uibutton(app.DataBaseTab, 'push');
            app.ModificarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ModificarButton_2Pushed, true);
            app.ModificarButton_2.Text = 'Modify 2';
            app.ModificarButton_3 = uibutton(app.DataBaseTab, 'push');
            app.ModificarButton_3.ButtonPushedFcn = createCallbackFcn(app, @ModificarButton_3Pushed, true);
            app.ModificarButton_3.Text = 'Modify 3';
            app.ModificarButton_4 = uibutton(app.DataBaseTab, 'push');
            app.ModificarButton_4.ButtonPushedFcn = createCallbackFcn(app, @ModificarButton_4Pushed, true);
            app.ModificarButton_4.Text = 'Modify 4';
            app.ModificarButton_5 = uibutton(app.DataBaseTab, 'push');
            app.ModificarButton_5.ButtonPushedFcn = createCallbackFcn(app, @ModificarButton_5Pushed, true);
            app.ModificarButton_5.Text = 'Modify 5';
            app.UploadfileTab = uitab(app.TabGroup);
            app.UploadfileTab.Title = 'Upload file';
            app.UploadfileTab.ButtonDownFcn = createCallbackFcn(app, @UploadfileTabButtonDown, true);
            app.UIAxes6 = uiaxes(app.UploadfileTab);
            title(app.UIAxes6, 'Audio Uploaded')
            xlabel(app.UIAxes6, 'Time')
            ylabel(app.UIAxes6, 'Voice Level')
            zlabel(app.UIAxes6, 'Z')
            app.MenuPanel = uipanel(app.UploadfileTab);
            app.MenuPanel.Title = 'Menu';
            app.GridLayout3 = uigridlayout(app.MenuPanel);
            app.PersonButtonGroup = uibuttongroup(app.GridLayout3);
            app.PersonButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PersonButtonGroupSelectionChanged, true);
            app.PersonButtonGroup.Title = 'Person';
            app.Button_5 = uiradiobutton(app.PersonButtonGroup);
            app.Button_5.Text = '1';
            app.Button_5.Value = true;
            app.Button_6 = uiradiobutton(app.PersonButtonGroup);
            app.Button_6.Text = '2';
            app.Button_7 = uiradiobutton(app.PersonButtonGroup);
            app.Button_7.Text = '3';
            app.Button_8 = uiradiobutton(app.PersonButtonGroup);
            app.Button_8.Text = '4';
            app.Button_9 = uiradiobutton(app.PersonButtonGroup);
            app.Button_9.Text = '5';
            app.CapturedButton = uiradiobutton(app.PersonButtonGroup);
            app.CapturedButton.Text = 'Captured';
            app.UploadfileButton = uibutton(app.GridLayout3, 'push');
            app.UploadfileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfileButtonPushed, true);
            app.UploadfileButton.Text = 'Upload file';
            app.ConfirmButton = uibutton(app.GridLayout3, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.AudioButtonGroup = uibuttongroup(app.GridLayout3);
            app.AudioButtonGroup.Title = 'Audio';
            app.Button_10 = uiradiobutton(app.AudioButtonGroup);
            app.Button_10.Text = '1';
            app.Button_10.Value = true;
            app.Button_11 = uiradiobutton(app.AudioButtonGroup);
            app.Button_11.Text = '2';
            app.Button_12 = uiradiobutton(app.AudioButtonGroup);
            app.Button_12.Text = '3';
            app.NameofthePersonTextAreaLabel = uilabel(app.SpeechRecognitionUIFigure);
            app.NameofthePersonTextAreaLabel.Text = 'Name of the Person';
            app.NameofthePersonTextArea = uitextarea(app.SpeechRecognitionUIFigure);
            app.NameofthePersonTextArea.Editable = 'off';
            app.ContextMenu = uicontextmenu(app.SpeechRecognitionUIFigure);
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SpeechRecognitionUIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.SpeechRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voiceRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpeechRecognitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpeechRecognitionUIFigure)
        end
    end
end